classdef LissajousFigureExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LissajousFigureExplorerUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        ResetMenu               matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        InputTab                matlab.ui.container.Tab
        YEqLabel                matlab.ui.control.Label
        XEqLabel                matlab.ui.control.Label
        Frequency1Slider        matlab.ui.control.Slider
        Frequency1SliderLabel   matlab.ui.control.Label
        Phase1SliderLabel       matlab.ui.control.Label
        Frequency2SliderLabel   matlab.ui.control.Label
        degreesLabel            matlab.ui.control.Label
        HzLabel_2               matlab.ui.control.Label
        HzLabel                 matlab.ui.control.Label
        Frequency2EditField     matlab.ui.control.NumericEditField
        Phase1EditField         matlab.ui.control.NumericEditField
        Frequency1EditField     matlab.ui.control.NumericEditField
        Phase1Slider            matlab.ui.control.Slider
        Frequency2Slider        matlab.ui.control.Slider
        AboutTab                matlab.ui.container.Tab
        Hyperlink               matlab.ui.control.Hyperlink
        ThisLissajousFigureExplorerAppispartoftheLabel  matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        LineColorDropDown       matlab.ui.control.DropDown
        LinecolorDropDownLabel  matlab.ui.control.Label
        AnimatePhaseCheckBox    matlab.ui.control.CheckBox
        AnimateTimeCheckBox     matlab.ui.control.CheckBox
        Axes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % App to explore Lissajous figures 
    %
    % Copyright 2021 The MathWorks, Inc.

    properties (Access = private)
        f1 % Frequency 1
        f2 % Frequency 2
        phi % Phase 1
        t % Time vector
        color % Line color
    end

    methods (Access = private)
        % Main function that plots the current Lissajous Figure
        function updatePlot(app,ii)
            % Check the function input arguments
            if nargin<2
                % If ii doesn't exist, define it covering all indices
                ii = 1:length(app.t);
            end

            % Calculate the new X and Y values
            x = cos(2*pi*app.f1.*app.t(ii) + deg2rad(app.phi));
            y = cos(2*pi*app.f2.*app.t(ii));

            % Plot the line
            plot(app.Axes,x,y,"Color",app.color)

            % Update the axes limits
            xlim(app.Axes,[-1.05 1.05]);
            ylim(app.Axes,[-1.05 1.05]);

            % Force the plot to update
            drawnow
        end
        
        function updateEquations(app)
            % Update the x(t) equation depending on if phase is + or -
            if app.phi>= 0
                app.XEqLabel.Text = "$$x(t) = \cos \left( 2\pi\cdot " + ...
                    num2str(round(app.f1)) + " t \; + \;" + ...
                    round(app.phi) + "\cdot\frac{\pi}{" + ...
                num2str(180) + "} \right)$$";
            else
                app.XEqLabel.Text = "$$x(t) = \cos \left( 2\pi\cdot " + ...
                num2str(round(app.f1)) + " t \; - \;" + ...
                round(abs(app.phi)) + "\cdot\frac{\pi}{" + ...
                num2str(180) + "} \right)$$";
            end
            
            % Update the y(t) equation
            app.YEqLabel.Text = "$$y(t) = \cos \left( 2\pi\cdot " + ...
                num2str(round(app.f2)) + " t \right)$$";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the initial property values
            app.f1 = round(app.Frequency1EditField.Value);
            app.f2 = round(app.Frequency2EditField.Value);
            app.phi = round(app.Phase1EditField.Value);
            app.t = 0:0.002:1;
            app.color = lines(1);

            % Update the equations displayed
            updateEquations(app);

            % Update the plot
            updatePlot(app);
            
            % Adjust plot aesthetics
            axis(app.Axes,"square")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LissajousFigureExplorerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {444, 444};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {448, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Frequency1Slider, Frequency2Slider, 
        % ...and 1 other component
        function SliderValueChanged(app, event)
            % Get the latest values from the sliders
            app.f1 = round(app.Frequency1Slider.Value);
            app.f2 = round(app.Frequency2Slider.Value);
            app.phi = round(app.Phase1Slider.Value);

            % Update the edit fields with rounded values
            app.Frequency1EditField.Value = app.f1;
            app.Frequency2EditField.Value = app.f2;
            app.Phase1EditField.Value = app.phi;

            % Update the equations displayed
            updateEquations(app);

            % Update the plot
            updatePlot(app);
        end

        % Value changed function: AnimatePhaseCheckBox
        function AnimatePhaseValueChanged(app, event)
            % Turn off the animate time check box
            app.AnimateTimeCheckBox.Value = false;

            % Animate over different phase values if the checkbox is enabled
            if app.AnimatePhaseCheckBox.Value == 1
                for ii = mod(app.phi+180+(0:2:360),360)-180
                    if app.AnimatePhaseCheckBox.Value == 1
                        % Update the phase values in the app
                        app.Phase1Slider.Value = ii;
                        app.Phase1EditField.Value = ii;
                        app.phi = ii;

                        % Update the equations displayed
                        updateEquations(app);

                        % Update the plot
                        updatePlot(app);
                    else
                        % Break the loop if the checkbox is not checked
                        break
                    end
                end
                
                % Turn off the check box when done
                app.AnimatePhaseCheckBox.Value = false;
            end
        end

        % Value changed function: Frequency1EditField, 
        % ...and 2 other components
        function EditFieldValueChanged(app, event)
            % Get latest values from the edit boxes
            app.f1 = round(app.Frequency1EditField.Value);
            app.f2 = round(app.Frequency2EditField.Value);
            app.phi = round(app.Phase1EditField.Value);

            % Ensure values are within allowable ranges
            app.f1 = min(app.f1,app.Frequency1Slider.Limits(2));
            app.f1 = max(app.f1,app.Frequency1Slider.Limits(1));
            app.f2 = min(app.f2,app.Frequency2Slider.Limits(2));
            app.f2 = max(app.f2,app.Frequency2Slider.Limits(1));
            app.phi = min(app.phi,app.Phase1Slider.Limits(2));
            app.phi = max(app.phi,app.Phase1Slider.Limits(1));

            % Update the sliders with updated values
            app.Frequency1Slider.Value = app.f1;
            app.Frequency1EditField.Value = app.f1;
            app.Frequency2Slider.Value = app.f2;
            app.Frequency2EditField.Value = app.f2;
            app.Phase1Slider.Value = app.phi;
            app.Phase1EditField.Value = app.phi;

            % Update the equations displayed
            updateEquations(app);

            % Update the plot
            updatePlot(app);
        end

        % Value changed function: AnimateTimeCheckBox
        function AnimateTimeValueChanged(app, event)
            % Turn off the animate phase check box
            app.AnimatePhaseCheckBox.Value = false;

            % Animate the line over time if the checkbox is enabled
            if app.AnimateTimeCheckBox.Value == 1
                for ii = 1:length(app.t)
                    if app.AnimateTimeCheckBox.Value == 1
                        % Update the plot
                        updatePlot(app,1:ii);
                    else
                        % Break the loop if the checkbox is not checked
                        break
                    end
                end

                % Turn off the check box when done
                app.AnimateTimeCheckBox.Value = false;
            end
        end

        % Value changed function: LineColorDropDown
        function LineColorDropDownValueChanged(app, event)
            % Get colors from lines colormap
            colors = lines(6);

            % Set the color proprerty depending on the dropdown selection
            switch(app.LineColorDropDown.Value)
                case "Blue"
                    app.color = colors(1,:);
                case "Red"
                    app.color = colors(2,:);
                case "Orange"
                    app.color = colors(3,:);
                case "Purple"
                    app.color = colors(4,:);
                case "Green"
                    app.color = colors(5,:);
                case "Cyan"
                    app.color = colors(6,:);
                case "Random"
                    app.color = rand(1,3);
            end
            
            % Update the plot
            updatePlot(app);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            % Reset the app properties
            app.f1 = 3;
            app.f2 = 2;
            app.phi = 45;
            app.color = lines(1);

            % Reset the app component values
            app.Frequency1Slider.Value = app.f1;
            app.Frequency1EditField.Value = app.f1;
            app.Frequency2Slider.Value = app.f2;
            app.Frequency2EditField.Value = app.f2;
            app.Phase1Slider.Value = app.phi;
            app.Phase1EditField.Value = app.phi;
            app.LineColorDropDown.Value = 'Blue';
            app.AnimateTimeCheckBox.Value = false;
            app.AnimatePhaseCheckBox.Value = false;
            
            % Update the equations displayed
            updateEquations(app);

            % Update the plot
            updatePlot(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % Disable any animations
            app.AnimateTimeCheckBox.Value = false;
            app.AnimatePhaseCheckBox.Value = false;

            % Close the main figure
            close(app.LissajousFigureExplorerUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LissajousFigureExplorerUIFigure = uifigure('Visible', 'off');
            app.LissajousFigureExplorerUIFigure.Name = 'Lissajous Figure Explorer';
            app.LissajousFigureExplorerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.LissajousFigureExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.LissajousFigureExplorerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.Frequency2Slider = uislider(app.InputTab);
            app.Frequency2Slider.Limits = [0 20];
            app.Frequency2Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Frequency2Slider.Value = 2;
            app.Phase1Slider = uislider(app.InputTab);
            app.Phase1Slider.Limits = [-180 180];
            app.Phase1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Phase1Slider.Value = 45;
            app.Frequency1EditField = uieditfield(app.InputTab, 'numeric');
            app.Frequency1EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Frequency1EditField.Value = 3;
            app.Phase1EditField = uieditfield(app.InputTab, 'numeric');
            app.Phase1EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Phase1EditField.Value = 45;
            app.Frequency2EditField = uieditfield(app.InputTab, 'numeric');
            app.Frequency2EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Frequency2EditField.Value = 2;
            app.HzLabel = uilabel(app.InputTab);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.InputTab);
            app.HzLabel_2.Text = 'Hz';
            app.degreesLabel = uilabel(app.InputTab);
            app.degreesLabel.Text = 'degrees';
            app.Frequency2SliderLabel = uilabel(app.InputTab);
            app.Frequency2SliderLabel.Text = 'Frequency 2';
            app.Phase1SliderLabel = uilabel(app.InputTab);
            app.Phase1SliderLabel.Text = 'Phase 1';
            app.Frequency1SliderLabel = uilabel(app.InputTab);
            app.Frequency1SliderLabel.Text = 'Frequency 1';
            app.Frequency1Slider = uislider(app.InputTab);
            app.Frequency1Slider.Limits = [0 20];
            app.Frequency1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Frequency1Slider.Value = 3;
            app.XEqLabel = uilabel(app.InputTab);
            app.XEqLabel.Interpreter = 'latex';
            app.XEqLabel.Text = '$$x(t) = cos(2\pi\cdot3t + 45\cdot\frac{\pi}{180})$$';
            app.YEqLabel = uilabel(app.InputTab);
            app.YEqLabel.Interpreter = 'latex';
            app.YEqLabel.Text = '$$y(t) = cos(2\pi\cdot2t)$$';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.ThisLissajousFigureExplorerAppispartoftheLabel = uilabel(app.AboutTab);
            app.ThisLissajousFigureExplorerAppispartoftheLabel.Interpreter = 'html';
            app.ThisLissajousFigureExplorerAppispartoftheLabel.Text = {'This Lissajous Figure Explorer app is part of Section 5 of the '; '<b>MATLAB Essentials</b> course on edX.'; ''; 'Use the <b>Input</b> tab to change the frequencies and phase shift of the x(t) and y(t) parametric equations. The equations form a Lissajous figure plotted on the right.'; ''; 'For more information, visit the <b>MATLAB Essentials</b> course page:'};
            app.Hyperlink = uihyperlink(app.AboutTab);
            app.Hyperlink.URL = 'https://www.edx.org/course/matlab-essentials';
            app.Hyperlink.Text = 'https://www.edx.org/course/matlab-essentials';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Axes = uiaxes(app.GridLayout2);
            title(app.Axes, 'Lissajous Figure')
            xlabel(app.Axes, 'x(t)')
            ylabel(app.Axes, 'y(t)')
            app.Axes.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AnimateTimeCheckBox = uicheckbox(app.GridLayout2);
            app.AnimateTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimateTimeValueChanged, true);
            app.AnimateTimeCheckBox.Text = 'Animate time';
            app.AnimatePhaseCheckBox = uicheckbox(app.GridLayout2);
            app.AnimatePhaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimatePhaseValueChanged, true);
            app.AnimatePhaseCheckBox.Text = 'Animate phase';
            app.LinecolorDropDownLabel = uilabel(app.GridLayout2);
            app.LinecolorDropDownLabel.Text = 'Line color';
            app.LineColorDropDown = uidropdown(app.GridLayout2);
            app.LineColorDropDown.Items = {'Blue', 'Red', 'Orange', 'Purple', 'Green', 'Cyan', 'Random'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LineColorDropDownValueChanged, true);
            app.LineColorDropDown.Value = 'Blue';
            % Show the figure after all components are created
            app.LissajousFigureExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LissajousFigureExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LissajousFigureExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LissajousFigureExplorerUIFigure)
        end
    end
end