classdef MatchingGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatchingGameUIFigure  matlab.ui.Figure
        PlayAgainButton       matlab.ui.control.Button
        GameText              matlab.ui.control.Label
        Button1               matlab.ui.control.StateButton
        Button2               matlab.ui.control.StateButton
        Button3               matlab.ui.control.StateButton
        Button4               matlab.ui.control.StateButton
        Button5               matlab.ui.control.StateButton
        Button6               matlab.ui.control.StateButton
        Button7               matlab.ui.control.StateButton
        Button8               matlab.ui.control.StateButton
        Button9               matlab.ui.control.StateButton
        Button10              matlab.ui.control.StateButton
        Button11              matlab.ui.control.StateButton
        Button12              matlab.ui.control.StateButton
    end

    % Matching image pair game
    %
    % Copyright 2021 The MathWorks, Inc.
    
    properties (Access = private)
        % List of buttons selected
        buttonNumsSelected = [];

        % Place holder for first button clicked
        firstSelected;
        
        % String vector of all image names
        allImages;
        
        % Images used in the game
        finalButtonList;

        % Game Score is equal to half the number of buttons
        gameScore;
    end
    
    methods (Access = private)

        function SelectButton(app,button,buttonNum)
            % Check if no buttons are selected
            if isempty(app.buttonNumsSelected)
                % Add button to list
                app.buttonNumsSelected(1) = buttonNum;
                % Change to picture
                button.Icon = app.finalButtonList(buttonNum);
                % Tell it this button was pressed first
                app.firstSelected = button.Tag;
                % Disable Button
                button.Enable = "off";
            % Check if one button is already selected
            elseif(size(app.buttonNumsSelected) == 1)
                % Add button to list
                app.buttonNumsSelected(2) = buttonNum;
                % Change picture
                button.Icon = app.finalButtonList(buttonNum);
                % Disable Button
                button.Enable = "off";

                % Check if Button1 icon is equal to Button2 icon in list
                % If the buttons are equal
                if(app.finalButtonList(app.buttonNumsSelected(1)) == app.finalButtonList(app.buttonNumsSelected(2)))
                    % Increment the score
                    app.gameScore = app.gameScore + 1;
                    % Clear the buttons selected list
                    app.buttonNumsSelected = [];

                    % Check if the player won the game
                    if (app.gameScore >= 6)
                        % Set text to say that they won
                        app.GameText.Text = "You win!";
                        % Change the text color to green
                        app.GameText.FontColor = [0.3 0.8 0.3];
                        app.GameText.FontWeight = "bold";
                        app.GameText.FontSize = 20;
                        % Show the play again button
                        app.PlayAgainButton.Visible = "on";
                    end

                % If the buttons are not equal
                else
                    % Pause for 1 secon
                    pause(1)

                    % Use a try block in case the player closes app early
                    try
                        % Search for the first button clicked
                        firstButton = findobj(app.MatchingGameUIFigure,"Tag",app.firstSelected);
                        % Set button1 value to 0
                        firstButton.Value = 0;
                        % Set button1 icon to blank
                        firstButton.Icon = "blank.png";
                        % Set button2 value to 0
                        button.Value = 0;
                        % Set button2 icon to blank
                        button.Icon = "blank.png";
                        % Enable button1
                        firstButton.Enable = "on";
                        % Enable button 2
                        button.Enable = "on";
                        % Empty the list
                        app.buttonNumsSelected = [];
                    catch
                        % Do nothing
                    end
                end
                 
            % Otherwise don't let the button be clicked
            else
                % Prevent the button from being clicked
                button.Value = 0;
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the images folder to the path
            addpath("GameImages");

            % Initialize game defaults
            app.PlayAgainButton.Visible = "off";
            app.gameScore = 0;
            app.GameText.Text = "Find all matching images by " + ...
                "clicking on two buttons at a time.";
            app.GameText.FontWeight = "normal";
            app.GameText.FontSize = 15;
            app.GameText.FontColor = [0 0 0];
            
            % Get all image files
            foundImages = dir(fullfile("GameImages","*.jpg"));
            
            % Convert the structure to a cell array
            cells = struct2cell(foundImages);

            % Get filenames from cell array
            names = cells(1,:);

            % Convert to string
            app.allImages = string(names');

            % Get number of images
            numImages = numel(app.allImages);

            % Check that there are enough images in the folder
            if(numImages >= 6)
                % Choose random selection of six images for game buttons
                usedImages = app.allImages(randperm(numImages,6));

                % Replicate the six images
                usedImages = repelem(usedImages,2);

                % Shuffle the image orders
                app.finalButtonList = usedImages(randperm(12));

                % Find all buttons and enable them
                buttons = findall(app.MatchingGameUIFigure,"Type","uistatebutton");
                set(buttons,"Enable","off");

                % Revert buttons back to their default value
                set(buttons,"Enable","on");
                set(buttons,"Icon","blank.png");
                set(buttons,"Value",0);
            else
                % If there are not enough images, then inform the user
                app.GameText.Text = "Not enough images to run the " + ...
                    "game. Please add more .jpg files to the " + ...
                    "GameImages folder.";
                app.GameText.FontColor = [1 0 0];
                
                % Find all buttons and disable them
                buttons = findall(app.MatchingGameUIFigure,"Type","uistatebutton");
                set(buttons,"Enable","off");
            end
        end

        % Value changed function: Button1
        function Button1ValueChanged(app, event)
            SelectButton(app,app.Button1,1);          
        end

        % Value changed function: Button2
        function Button2ValueChanged(app, event)
            SelectButton(app,app.Button2,2);
        end

        % Value changed function: Button3
        function Button3ValueChanged(app, event)
            SelectButton(app,app.Button3,3);
        end

        % Value changed function: Button4
        function Button4ValueChanged(app, event)
            SelectButton(app,app.Button4,4);
        end

        % Value changed function: Button5
        function Button5ValueChanged(app, event)
            SelectButton(app,app.Button5,5);
        end

        % Value changed function: Button6
        function Button6ValueChanged(app, event)
            SelectButton(app,app.Button6,6);
        end

        % Value changed function: Button7
        function Button7ValueChanged(app, event)
            SelectButton(app,app.Button7,7);
        end

        % Value changed function: Button8
        function Button8ValueChanged(app, event)
            SelectButton(app,app.Button8,8);
        end

        % Value changed function: Button9
        function Button9ValueChanged(app, event)
            SelectButton(app,app.Button9,9);
        end

        % Value changed function: Button10
        function Button10ValueChanged(app, event)
            SelectButton(app,app.Button10,10);
        end

        % Value changed function: Button11
        function Button11ValueChanged(app, event)
            SelectButton(app,app.Button11,11);
        end

        % Value changed function: Button12
        function Button12ValueChanged(app, event)
            SelectButton(app,app.Button12,12);
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
            % Call the startup function
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MatchingGameUIFigure = uifigure('Visible', 'off');
            app.MatchingGameUIFigure.Name = 'Matching Game';
            app.Button12 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button12.ValueChangedFcn = createCallbackFcn(app, @Button12ValueChanged, true);
            app.Button12.Tag = 'Button12';
            app.Button12.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button12.Text = '';
            app.Button11 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button11.ValueChangedFcn = createCallbackFcn(app, @Button11ValueChanged, true);
            app.Button11.Tag = 'Button11';
            app.Button11.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button11.Text = '';
            app.Button10 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button10.ValueChangedFcn = createCallbackFcn(app, @Button10ValueChanged, true);
            app.Button10.Tag = 'Button10';
            app.Button10.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button10.Text = '';
            app.Button9 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button9.ValueChangedFcn = createCallbackFcn(app, @Button9ValueChanged, true);
            app.Button9.Tag = 'Button9';
            app.Button9.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button9.Text = '';
            app.Button8 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button8.ValueChangedFcn = createCallbackFcn(app, @Button8ValueChanged, true);
            app.Button8.Tag = 'Button8';
            app.Button8.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button8.Text = '';
            app.Button7 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button7.ValueChangedFcn = createCallbackFcn(app, @Button7ValueChanged, true);
            app.Button7.Tag = 'Button7';
            app.Button7.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button7.Text = '';
            app.Button6 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button6.ValueChangedFcn = createCallbackFcn(app, @Button6ValueChanged, true);
            app.Button6.Tag = 'Button6';
            app.Button6.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button6.Text = '';
            app.Button5 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button5.ValueChangedFcn = createCallbackFcn(app, @Button5ValueChanged, true);
            app.Button5.Tag = 'Button5';
            app.Button5.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button5.Text = '';
            app.Button4 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button4.ValueChangedFcn = createCallbackFcn(app, @Button4ValueChanged, true);
            app.Button4.Tag = 'Button4';
            app.Button4.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button4.Text = '';
            app.Button3 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button3.ValueChangedFcn = createCallbackFcn(app, @Button3ValueChanged, true);
            app.Button3.Tag = 'Button3';
            app.Button3.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button3.Text = '';
            app.Button2 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button2.Tag = 'Button2';
            app.Button2.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button2.Text = '';
            app.Button1 = uibutton(app.MatchingGameUIFigure, 'state');
            app.Button1.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button1.Tag = 'Button1';
            app.Button1.Icon = fullfile(pathToMLAPP, 'GameImages', 'blank.png');
            app.Button1.Text = '';
            app.GameText = uilabel(app.MatchingGameUIFigure);
            app.GameText.Text = 'Find all matching images by clicking on two buttons at a time.';
            app.PlayAgainButton = uibutton(app.MatchingGameUIFigure, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again';
            % Show the figure after all components are created
            app.MatchingGameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatchingGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatchingGameUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatchingGameUIFigure)
        end
    end
end